classdef simulation_ui2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SetsimulationPanel            matlab.ui.container.Panel
        AgentsEditFieldLabel          matlab.ui.control.Label
        AgentsEditField               matlab.ui.control.NumericEditField
        AttackerdEditFieldLabel       matlab.ui.control.Label
        AttackerdEditField            matlab.ui.control.NumericEditField
        StartsimulationButton         matlab.ui.control.Button
        ReplaysimulationButton        matlab.ui.control.Button
        SetnumberofiterationsSpinnerLabel  matlab.ui.control.Label
        SetnumberofiterationsSpinner  matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        Env % multi-robot environment
        OcMap;
        FieldLen = 100;% length of field side
        PZoneLen = 20;% Protected Zone side length
        Poses;
        FrameSize = 10;
    end
    
    methods (Access = private)
        
        function res = get_atacker_init_pos(app)
            pos1 = (rand(1,1)-0.5)*2; % get rand in [-1,1]
            negIdx = (pos1<0);
            posIdx = (pos1>=0);
            % map to [-10,0] or [100,110]
            pos1 = app.FrameSize .* pos1;
            pos1 = ((app.FieldLen/2)+ pos1).*posIdx ...
                + (-(app.FieldLen/2)+ pos1).*negIdx  ...
                + app.FieldLen/2;
            % map to [-10,110]
            pos2 = (-app.FrameSize+(app.FieldLen+(2*app.FrameSize)) .* rand(1,1));
            
            coinFlip = (rand(1,1)-0.5)*2;
            if coinFlip >= 0
                res = [pos1 pos2];
            else
                res = [pos2 pos1];
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create occupancy map
            app.OcMap = binaryOccupancyMap(app.FieldLen, app.FieldLen);
            fieldMid = fix(app.FieldLen/2);
            pzMid = fix (app.PZoneLen/2);
            x = fieldMid-pzMid+1:fieldMid+pzMid;
            y = fieldMid-pzMid+1:fieldMid+pzMid;
            [p,q] = meshgrid(x,y);
            xy = [p(:) q(:)];
            setOccupancy(app.OcMap, xy, ones(app.PZoneLen^2,1))
        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)

            
            %% Create a multi-robot environment
            
            % get User values
            numOfAgents = app.AgentsEditField.Value;
            numOfAttackers = app.AttackerdEditField.Value;
            mumIter = app.SetnumberofiterationsSpinner.Value;
            
            numRobots = numOfAttackers+numOfAgents;
            
            % init multi-robot environment
            app.Env = MultiRobotEnv(numRobots);
            app.Env.robotRadius = 0.1;
            app.Env.showTrajectory = false;
            app.Env.mapName = "map";
            
            % divide robots to groups
            a = repmat([0 0 1],[numOfAgents 1]);
            b = repmat([1 0 0],[numOfAttackers 1]);
            app.Env.robotColors = [a;b];
            
            % Initialize poses randomly, and add bias to each "team"
            poses = [10*(rand(2,numRobots) - 0.5); ...
            pi*rand(1,numRobots)];
            angleBias = 2*pi*(1:numRobots)/numTeams;
            poses(1:2,:) = poses(1:2,:) + 2.5*[sin(angleBias);cos(angleBias)]+20;
            
            figure
            show(app.OcMap)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetsimulationPanel = uipanel(app.UIFigure);
            app.SetsimulationPanel.Title = 'Set simulation';
            app.AgentsEditFieldLabel = uilabel(app.SetsimulationPanel);
            app.AgentsEditFieldLabel.Text = 'Agents';
            app.AgentsEditField = uieditfield(app.SetsimulationPanel, 'numeric');
            app.AgentsEditField.Value = 2;
            app.AttackerdEditFieldLabel = uilabel(app.SetsimulationPanel);
            app.AttackerdEditFieldLabel.Text = 'Attackerd';
            app.AttackerdEditField = uieditfield(app.SetsimulationPanel, 'numeric');
            app.AttackerdEditField.Value = 1;
            app.StartsimulationButton = uibutton(app.SetsimulationPanel, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Text = 'Start simulation';
            app.ReplaysimulationButton = uibutton(app.SetsimulationPanel, 'push');
            app.ReplaysimulationButton.Text = 'Replay simulation';
            app.SetnumberofiterationsSpinnerLabel = uilabel(app.SetsimulationPanel);
            app.SetnumberofiterationsSpinnerLabel.Text = 'Set number of iterations';
            app.SetnumberofiterationsSpinner = uispinner(app.SetsimulationPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation_ui2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end